classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        graph                matlab.ui.control.UIAxes
        TransformationLabel  matlab.ui.control.Label
        transformation       matlab.ui.control.DropDown
        gammaSliderLabel     matlab.ui.control.Label
        gammaSlider          matlab.ui.control.Slider
        aSliderLabel         matlab.ui.control.Label
        aSlider              matlab.ui.control.Slider
        bSliderLabel         matlab.ui.control.Label
        bSlider              matlab.ui.control.Slider
        gammaValue           matlab.ui.control.TextArea
        aValue               matlab.ui.control.TextArea
        bValue               matlab.ui.control.TextArea
        PlotButton           matlab.ui.control.Button
    end


    methods (Access = public)
    
        function replot(app)
            red = evalin('base','red');
            blue = evalin('base','blue');
            data = evalin('base','data');
            trans = evalin('base','trans');
            gamma = evalin('base','gamma');
            a = evalin('base','a');
            b = evalin('base','b');
            
            size_r = size(red,2);
            size_b = size(blue,2);
            val_r = ones(1,size_r);
            val_b = -ones(1,size_b);
            
            minx = min(min(red(1,:)),min(blue(1,:)));
            maxx = max(max(red(1,:)),max(blue(1,:)));
            miny = min(min(red(2,:)),min(blue(2,:)));
            maxy = max(max(red(2,:)),max(blue(2,:)));

            grd = [];
            
            fraction = (maxx - minx)/(maxy - miny);
            if (fraction > 0.7) && (fraction < 1.5)
                fraction = 1;
            end
            for i = linspace(minx,maxx,25*fraction)
                for j = linspace(miny,maxy,25)
                    point = [i;j];
                    grd = [grd(:,:) point];
                end
            end

            classes = SVMLS([red blue],[val_r val_b],data,trans,gamma,a,b);
            grid_vals = SVMLS([red blue],[val_r val_b],grd,trans,gamma,a,b);

            cla(app.graph);
            app.graph.XLim = [minx maxx];
            app.graph.YLim = [miny maxy];
            hold(app.graph, 'on');
            plot(app.graph, red(1,:),red(2,:),  'red *');
            plot(app.graph, blue(1,:),blue(2,:), 'blue *');
            
            for i = 1:size(grid_vals,1)
                if (grid_vals(i) < 1)
                    plot(app.graph, grd(1,i), grd(2,i), 'black +');
                end
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            red_x = rand(1, 20)+2;
            red_y = rand(1, 20)+2;
            blue_x = rand(1, 20)+1.5;
            blue_y = rand(1, 20)+1.5;
            red = [red_x; red_y];
            blue = [blue_x; blue_y];
            data = [];
            
            assignin('base','red',red);
            assignin('base','blue',blue);
            assignin('base','data',data);
            assignin('base','trans',3);
            assignin('base','gamma',1);
            assignin('base','a',0.7);
            assignin('base','b',1);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            replot(app);
        end

        % Value changed function: aSlider
        function aSliderValueChanged(app, event)
            a = app.aSlider.Value;
            assignin('base','a',a);
            replot(app);
        end

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            changinga = event.Value;
            app.aValue.Value = num2str(changinga);
        end

        % Value changed function: bSlider
        function bSliderValueChanged(app, event)
            b = app.bSlider.Value;
            assignin('base','b',b);
            replot(app);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            changingb = event.Value;
            app.bValue.Value = num2str(changingb);
        end

        % Value changed function: gammaSlider
        function gammaSliderValueChanged(app, event)
            gamma = app.gammaSlider.Value;
            assignin('base','gamma',gamma);
            replot(app);
        end

        % Value changing function: gammaSlider
        function gammaSliderValueChanging(app, event)
            changinggamma = event.Value;
            app.gammaValue.Value = num2str(changinggamma);
        end

        % Value changed function: transformation
        function transformationValueChanged(app, event)
            trans = app.transformation.Value;
            if strncmp('Linear',trans,6)
                assignin('base','trans',1);
            elseif strncmp('Polynomial',trans,10)
                assignin('base','trans',2);
            elseif strncmp('Exponential',trans,11)
                assignin('base','trans',3);
            elseif strncmp('Tanh',trans,4)
                assignin('base','trans',4);
            end
            replot(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'SVM')
            xlabel(app.graph, 'X')
            ylabel(app.graph, 'Y')
            app.TransformationLabel = uilabel(app.UIFigure);
            app.TransformationLabel.Text = 'Transformation';
            app.transformation = uidropdown(app.UIFigure);
            app.transformation.Items = {'Linear', 'Polynomial', 'Exponential', 'Tanh'};
            app.transformation.ValueChangedFcn = createCallbackFcn(app, @transformationValueChanged, true);
            app.transformation.Value = 'Exponential';
            app.gammaSliderLabel = uilabel(app.UIFigure);
            app.gammaSliderLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0 10];
            app.gammaSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.gammaSlider.ValueChangedFcn = createCallbackFcn(app, @gammaSliderValueChanged, true);
            app.gammaSlider.ValueChangingFcn = createCallbackFcn(app, @gammaSliderValueChanging, true);
            app.gammaSlider.Value = 1;
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 1];
            app.aSlider.MajorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.aSlider.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanged, true);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aSlider.Value = 0.7;
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0 5];
            app.bSlider.MajorTicks = [0 1 2 3 4 5];
            app.bSlider.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanged, true);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bSlider.Value = 1;
            app.gammaValue = uitextarea(app.UIFigure);
            app.gammaValue.Value = {'1'};
            app.aValue = uitextarea(app.UIFigure);
            app.aValue.Value = {'0.7'};
            app.bValue = uitextarea(app.UIFigure);
            app.bValue.Value = {'1'};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end